classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileNameLabel             matlab.ui.control.Label
        currentFile               matlab.ui.control.Label
        chooseFileButton          matlab.ui.control.Button
        HzSlider_2Label           matlab.ui.control.Label
        bandSlider1               matlab.ui.control.Slider
        title                     matlab.ui.control.Label
        HzSliderLabel             matlab.ui.control.Label
        bandSlider2               matlab.ui.control.Slider
        HzSlider_3Label           matlab.ui.control.Label
        bandSlider3               matlab.ui.control.Slider
        HzSlider_4Label           matlab.ui.control.Label
        bandSlider4               matlab.ui.control.Slider
        KHzSliderLabel            matlab.ui.control.Label
        bandSlider5               matlab.ui.control.Slider
        KHzSlider_2Label          matlab.ui.control.Label
        bandSlider6               matlab.ui.control.Slider
        KHzSlider_3Label          matlab.ui.control.Label
        bandSlider7               matlab.ui.control.Slider
        KHzLabel                  matlab.ui.control.Label
        bandSlider8               matlab.ui.control.Slider
        KHzSlider_5Label          matlab.ui.control.Label
        bandSlider9               matlab.ui.control.Slider
        FilterTypeButtonGroup     matlab.ui.container.ButtonGroup
        FIR                       matlab.ui.control.RadioButton
        IIR                       matlab.ui.control.RadioButton
        FilterButton              matlab.ui.control.Button
        PlayButton                matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        SampleRateEditFieldLabel  matlab.ui.control.Label
        sampleRateInput           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        file_name
        file_path
        filter_type = 0;
        band1 = 0;
        band2 = 0;
        band3 = 0;
        band4 = 0;
        band5 = 0;
        band6 = 0;
        band7 = 0;
        band8 = 0;
        band9 = 0;
        sample_rate = 0;
        audio_data;
    end


    methods (Access = private)

        % Button pushed function: chooseFileButton
        function chooseFileButtonPushed(app, event)
            [f_name,f_path] = uigetfile({'*.wav'},'File Selector');
            if(f_name ~= 0)
                app.file_name = f_name;
                app.file_path = strcat(f_path, f_name);
                [app.audio_data, app.sample_rate] = audioread(app.file_path); % reads the audio data
                app.sampleRateInput.Value = app.sample_rate; % shows the file sample rate in the input box
                app.currentFile.Text = app.file_name; % updates current file
            end
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterTypeButtonGroup.SelectedObject;
            if(selectedButton == app.FIR)
                app.filter_type = 0;
            else
                app.filter_type = 1;
            end
        end

        % Value changed function: bandSlider1
        function bandSlider1ValueChanged(app, event)
            value = app.bandSlider1.Value;
            app.band1 = value;
        end

        % Value changed function: bandSlider2
        function bandSlider2ValueChanged(app, event)
            value = app.bandSlider2.Value;
            app.band2 = value;
        end

        % Value changed function: bandSlider3
        function bandSlider3ValueChanged(app, event)
            value = app.bandSlider3.Value;
            app.band3 = value;
        end

        % Value changed function: bandSlider4
        function bandSlider4ValueChanged(app, event)
            value = app.bandSlider4.Value;
            app.band4 = value;
        end

        % Value changed function: bandSlider5
        function bandSlider5ValueChanged(app, event)
            value = app.bandSlider5.Value;
            app.band5 = value;
        end

        % Value changed function: bandSlider6
        function bandSlider6ValueChanged(app, event)
            value = app.bandSlider6.Value;
            app.band6 = value;
        end

        % Value changed function: bandSlider7
        function bandSlider7ValueChanged(app, event)
            value = app.bandSlider7.Value;
            app.band7 = value;
        end

        % Value changed function: bandSlider8
        function bandSlider8ValueChanged(app, event)
            value = app.bandSlider8.Value;
            app.band8 = value;
        end

        % Value changed function: bandSlider9
        function bandSlider9ValueChanged(app, event)
            value = app.bandSlider9.Value;
            app.band9 = value;
        end

        % Value changed function: sampleRateInput
        function sampleRateInputValueChanged(app, event)
            value = app.sampleRateInput.Value;
            app.sample_rate = value;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                sound(app.audio_data,app.sample_rate); % plays unfiltered sound for now
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound; % stops unfiltered sound for now
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name:';
            app.currentFile = uilabel(app.UIFigure);
            app.currentFile.Text = 'Choose a file ...';
            app.chooseFileButton = uibutton(app.UIFigure, 'push');
            app.chooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileButtonPushed, true);
            app.chooseFileButton.Text = 'Choose';
            app.HzSlider_2Label = uilabel(app.UIFigure);
            app.HzSlider_2Label.Text = '0 - 170 Hz';
            app.bandSlider1 = uislider(app.UIFigure);
            app.bandSlider1.Limits = [-10 10];
            app.bandSlider1.ValueChangedFcn = createCallbackFcn(app, @bandSlider1ValueChanged, true);
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Audio Equalizer';
            app.HzSliderLabel = uilabel(app.UIFigure);
            app.HzSliderLabel.Text = '170 - 310 Hz';
            app.bandSlider2 = uislider(app.UIFigure);
            app.bandSlider2.Limits = [-10 10];
            app.bandSlider2.ValueChangedFcn = createCallbackFcn(app, @bandSlider2ValueChanged, true);
            app.HzSlider_3Label = uilabel(app.UIFigure);
            app.HzSlider_3Label.Text = '310 - 600 Hz';
            app.bandSlider3 = uislider(app.UIFigure);
            app.bandSlider3.Limits = [-10 10];
            app.bandSlider3.ValueChangedFcn = createCallbackFcn(app, @bandSlider3ValueChanged, true);
            app.HzSlider_4Label = uilabel(app.UIFigure);
            app.HzSlider_4Label.Text = '600 - 1000 Hz';
            app.bandSlider4 = uislider(app.UIFigure);
            app.bandSlider4.Limits = [-10 10];
            app.bandSlider4.ValueChangedFcn = createCallbackFcn(app, @bandSlider4ValueChanged, true);
            app.KHzSliderLabel = uilabel(app.UIFigure);
            app.KHzSliderLabel.Text = '1 - 3 KHz';
            app.bandSlider5 = uislider(app.UIFigure);
            app.bandSlider5.Limits = [-10 10];
            app.bandSlider5.ValueChangedFcn = createCallbackFcn(app, @bandSlider5ValueChanged, true);
            app.KHzSlider_2Label = uilabel(app.UIFigure);
            app.KHzSlider_2Label.Text = '3 - 6 KHz';
            app.bandSlider6 = uislider(app.UIFigure);
            app.bandSlider6.Limits = [-10 10];
            app.bandSlider6.ValueChangedFcn = createCallbackFcn(app, @bandSlider6ValueChanged, true);
            app.KHzSlider_3Label = uilabel(app.UIFigure);
            app.KHzSlider_3Label.Text = '6 - 12 KHz';
            app.bandSlider7 = uislider(app.UIFigure);
            app.bandSlider7.Limits = [-10 10];
            app.bandSlider7.ValueChangedFcn = createCallbackFcn(app, @bandSlider7ValueChanged, true);
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = '12 - 14 KHz';
            app.bandSlider8 = uislider(app.UIFigure);
            app.bandSlider8.Limits = [-10 10];
            app.bandSlider8.ValueChangedFcn = createCallbackFcn(app, @bandSlider8ValueChanged, true);
            app.KHzSlider_5Label = uilabel(app.UIFigure);
            app.KHzSlider_5Label.Text = '14 - 16 KHz';
            app.bandSlider9 = uislider(app.UIFigure);
            app.bandSlider9.Limits = [-10 10];
            app.bandSlider9.ValueChangedFcn = createCallbackFcn(app, @bandSlider9ValueChanged, true);
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.FIR = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIR.Text = 'FIR';
            app.FIR.Value = true;
            app.IIR = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIR.Text = 'IIR';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.SampleRateEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.sampleRateInput = uieditfield(app.UIFigure, 'numeric');
            app.sampleRateInput.RoundFractionalValues = 'on';
            app.sampleRateInput.ValueChangedFcn = createCallbackFcn(app, @sampleRateInputValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end